classdef fly_on_the_ball_analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        File                          matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        CloseMenu                     matlab.ui.container.Menu
        SaveClassificationMenu        matlab.ui.container.Menu
        SaveDisplayMenu               matlab.ui.container.Menu
        SaveVideoMenu                 matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        CamerasMenu                   matlab.ui.container.Menu
        VelocityMenu                  matlab.ui.container.Menu
        PathMenu                      matlab.ui.container.Menu
        StimulusMenu                  matlab.ui.container.Menu
        CameraFramesMenu              matlab.ui.container.Menu
        KeypointsMenu                 matlab.ui.container.Menu
        BehaviorClassificationMenu    matlab.ui.container.Menu
        SwingClassificationMenu       matlab.ui.container.Menu
        DisplayPanel                  matlab.ui.container.Panel
        AdjustDisplayButton           matlab.ui.control.Button
        KeypointsCheckBox             matlab.ui.control.CheckBox
        ClassifyBehaviorPanel         matlab.ui.container.Panel
        PushingCheckBox               matlab.ui.control.CheckBox
        AnalyzeCheckBox               matlab.ui.control.CheckBox
        RestingCheckBox               matlab.ui.control.CheckBox
        WalkingCheckBox               matlab.ui.control.CheckBox
        FLgroomingCheckBox            matlab.ui.control.CheckBox
        HLgroomingCheckBox            matlab.ui.control.CheckBox
        FlightCheckBox                matlab.ui.control.CheckBox
        OtherCheckBox                 matlab.ui.control.CheckBox
        StartFrameButtonBehavior      matlab.ui.control.Button
        EndFrameButtonBehavior        matlab.ui.control.Button
        StartFrameEditFieldBehavior   matlab.ui.control.NumericEditField
        EndFrameEditFieldBehavior     matlab.ui.control.NumericEditField
        SetToOneButtonBehavior        matlab.ui.control.Button
        SetToZeroButtonBehavior       matlab.ui.control.Button
        ClassifySwingPanel            matlab.ui.container.Panel
        AllCheckBox                   matlab.ui.control.CheckBox
        L1CheckBox                    matlab.ui.control.CheckBox
        L2CheckBox                    matlab.ui.control.CheckBox
        L3CheckBox                    matlab.ui.control.CheckBox
        R1CheckBox                    matlab.ui.control.CheckBox
        R2CheckBox                    matlab.ui.control.CheckBox
        R3CheckBox                    matlab.ui.control.CheckBox
        StartFrameButtonSwing         matlab.ui.control.Button
        EndFrameButtonSwing           matlab.ui.control.Button
        StartFrameEditFieldSwing      matlab.ui.control.NumericEditField
        EndFrameEditFieldSwing        matlab.ui.control.NumericEditField
        SetToOneButtonSwing           matlab.ui.control.Button
        SetToZeroButtonSwing          matlab.ui.control.Button
        AdjustKeypointsPanel          matlab.ui.container.Panel
        AdjustKeypointsButton         matlab.ui.control.Button
        KeypointNamesCheckBox         matlab.ui.control.CheckBox
        InteractiveKeypointsCheckBox  matlab.ui.control.CheckBox
        Camera01CheckBox              matlab.ui.control.CheckBox
        Camera02CheckBox              matlab.ui.control.CheckBox
        ExportKeypointsButton         matlab.ui.control.Button
        SingleRwdButton               matlab.ui.control.Button
        SingleFwdButton               matlab.ui.control.Button
        FramesSlider                  matlab.ui.control.Slider
        FramesSliderLabel             matlab.ui.control.Label
        FwdButton                     matlab.ui.control.Button
        RwdButton                     matlab.ui.control.Button
        ReplaySpeedSlider             matlab.ui.control.Slider
        ReplayspeedSliderLabel        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        TrialLabel                    matlab.ui.control.Label
        CurrentFrameLabel             matlab.ui.control.Label
        FrameLabel                    matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        CurrentTimeLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        config 
        daq_data
        time
        treadmill_data
        stimulus_data
        camera_data
        classification_data
        deeplabcut
        current_frame
        current_time
        figure_handles
    end
    
    methods (Access = private)
        
        function process_daq_data(app)
            [time,camera_data,treadmill_data,stimulus_data] = utils_process_daq_data(app.daq_data,app.config); 
            app.time = time;
            app.camera_data = camera_data;
            app.treadmill_data = treadmill_data;
            app.stimulus_data = stimulus_data;
        end

        function process_treadmill_data(app)
            treadmill_data = utils_process_treadmill_data(app.treadmill_data,app.config);
            app.treadmill_data = treadmill_data;
        end

        function process_deeplabcut_data(app)       
            camera_data = utils_process_deeplabcut_data(app.camera_data,app.config);
            app.camera_data = camera_data;
        end
           
        function classify_behavior(app)
            network = 'lstm_network_behavior.mat';
            % Same order as training
            keypoint_data = [...
                app.camera_data.L1_leg_vector_velocity, ...
                app.camera_data.L2_leg_vector_velocity, ...
                app.camera_data.L3_leg_vector_velocity, ...
                app.camera_data.R1_leg_vector_velocity, ...
                app.camera_data.R2_leg_vector_velocity, ...
                app.camera_data.R3_leg_vector_velocity]'; 

            % The output of utils_classify_behavior() is a matrix where the
            % columns are sorted based on the class names in alphabetical
            % order. Assign the columns to the correct column in the
            % behavior matrix. 
            % Column 1: Grooming T1 (target column: 4)
            % Column 2: Grooming T3 (target column: 5)
            % Column 3: Other (target column: 7)
            % Column 4: Resting (target column: 2)
            % Column 5: Walking (target column: 3)
            target_columns = [4,5,7,2,3];
            sliding_window = app.config.classification.sliding_window_behavior;
            app.classification_data.behavior(:,target_columns) = utils_classify_behavior(keypoint_data,network,sliding_window);

            % Initalize matrix for proofreading if it does not exist
            if ~isfield(app.classification_data,'proofread_behavior')
                app.classification_data.proofread_behavior = app.classification_data.behavior;
            end
        end

        function classify_swing(app)
            sliding_window = app.config.classification.sliding_window_swing;
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_data_',camera_name])
                    camera_orientation = app.config.camera.camera_orientations{iCamera};
                    if strcmp(camera_orientation,'left')
                        label = 'L';
                    else
                        label = 'R';
                    end
                    for iLeg = 1:3
                        keypoint_data = app.camera_data.([label,num2str(iLeg),'_leg_vector_velocity'])';
                        
                        network = ['lstm_network_I',num2str(iLeg),'_swing_onset.mat'];
                        swing_onsets = utils_classify_swing(keypoint_data,network,sliding_window);

                        network = ['lstm_network_I',num2str(iLeg),'_swing_offset.mat'];
                        swing_offsets = utils_classify_swing(keypoint_data,network,sliding_window);

                        %network = ['lstm_network_I',num2str(iLeg),'_swing.mat'];
                        %[swing_onsets, swing_offsets] = utils_classify_swing(keypoint_data,network,sliding_window);

                        if strcmp(camera_orientation,'left')
                            label = 'L';
                        else
                            label = 'R';
                        end
                        app.classification_data.([label,num2str(iLeg),'_swing_onsets']) = swing_onsets;
                        app.classification_data.([label,num2str(iLeg),'_swing_offsets']) = swing_offsets;
                    end
                end
            end

            % Convert swing onsets and swing offsets to binary vector
            classifications = {'L1','L2','L3','R1','R2','R3'};
            for iClassification = 1:numel(classifications)
                swing_onsets = app.classification_data.([classifications{iClassification},'_swing_onsets']);
                swing_offsets = app.classification_data.([classifications{iClassification},'_swing_offsets']);
                for iSwing = 1:numel(swing_onsets)
                    swing_onset = swing_onsets(iSwing);
                    swing_offset = swing_offsets(find(swing_offsets>swing_onsets(iSwing),1,'first'));
                    app.classification_data.swing(swing_onset:swing_offset,iClassification) = 1;
                end
            end

            % Initalize matrix for proofreading if it does not exist
            if ~isfield(app.classification_data,'proofread_swing')
                app.classification_data.proofread_swing = app.classification_data.swing;
            end
        end

        function plot_camera(app)
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_',camera_name]) 
                    % Close camera window if open
                    if isfield(app.figure_handles,camera_name)
                        if isvalid(app.figure_handles.(camera_name))
                            close(app.figure_handles.(camera_name))
                        end
                    end
                    % Load data
                    vid_data = app.camera_data.(['vid_data_',camera_name])(app.current_frame).cdata;
                    % Plot data
                    app.figure_handles.(camera_name) = figure;
                    imshow(vid_data,'border','tight');
                    set(app.figure_handles.(camera_name), ...
                        'menubar','none', ...  
                        'NumberTitle','off', ...
                        'Name', camera_name, ...
                        'Position', double(app.config.display.(['pos_',camera_name])), ...
                        'doublebuffer','off');
                end
            end
        end

        function overlay_keypoints(app)
            keypoint_names = app.config.deeplabcut.keypoint_names( ...
                double(app.config.deeplabcut.keypoints_to_overlay));
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_data_',camera_name])
                    figure(app.figure_handles.(camera_name))
                    hold on
                    for iKeypoint = 1:numel(keypoint_names)
                        keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                        plot(...
                            app.camera_data.(['data_',camera_name,'_filtered'])(app.current_frame,keypoint_indices(1)), ...
                            app.camera_data.(['data_',camera_name,'_filtered'])(app.current_frame,keypoint_indices(2)), ...
                            'o', ...
                            'Color', app.config.display.(['color_',keypoint_names{iKeypoint}]), ...
                            'MarkerFaceColor', app.config.display.(['color_',keypoint_names{iKeypoint}]));
                    end
                    % Overlay leg vectors
                    camera_orientation = app.config.camera.camera_orientations{iCamera};
                    if strcmp(camera_orientation,'left')
                        label = 'L';
                    else
                        label = 'R';
                    end
                    for iLeg = 1:3
                        leg_vector_video_position = app.camera_data.([label,num2str(iLeg),'_leg_vector_video_position'])(app.current_frame,:);
                        plot([leg_vector_video_position(1), leg_vector_video_position(2)], ...
                            [leg_vector_video_position(3), leg_vector_video_position(4)], ...
                            '-w');
                    end
                    hold off
                end
            end
        end

        function overlay_interactive_keypoints(app)
            if (app.Camera01CheckBox.Value ...
                    || app.Camera02CheckBox.Value) 
                 if app.Camera01CheckBox.Value
                     iCamera = 1; 
                 else
                     iCamera = 2;
                 end
             
                 camera_name = app.config.camera.camera_names{iCamera};
                 if app.config.camera.(['is_',camera_name]) 
                    % Close camera window if open
                    if isfield(app.figure_handles,[camera_name,'_set_keypoints'])
                        if isvalid(app.figure_handles.([camera_name,'_set_keypoints']))
                            close(app.figure_handles.([camera_name,'_set_keypoints']))
                        end
                    end
                    % Plot image
                    vid_data = read(app.camera_data.(['vid_obj_',camera_name]),app.current_frame);
                    app.figure_handles.([camera_name,'_set_keypoints']) = figure;
                    imshow(vid_data,'border','tight');
                    set(app.figure_handles.([camera_name,'_set_keypoints']), ...
                        'menubar','none', ...
                        'NumberTitle','off', ...
                        'Name', camera_name, ...
                        'Position', double(app.config.display.(['pos_',camera_name,'_set_keypoints'])), ...
                        'doublebuffer','off');
                    
                    % Plot keypoints 
                    if app.InteractiveKeypointsCheckBox.Value
                        keypoint_names = app.config.deeplabcut.keypoint_names;
                        hold on
                        n_keypoint = 0;
                        for iKeypoint = 1:numel(keypoint_names)
                            keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                            if app.config.camera.(['is_data_',camera_name])
                                position = app.camera_data.(['data_',camera_name])(app.current_frame,keypoint_indices);
                            else
                                position = [10,10+n_keypoint*10];
                                n_keypoint = n_keypoint+1;
                            end
                            app.deeplabcut.(keypoint_names{iKeypoint}) = images.roi.Point(...
                                gca, ...
                                'Position', position, ...
                                'Label',keypoint_names{iKeypoint}, ...
                                'LabelVisible','hover', ... 
                                'Color', app.config.display.(['color_',keypoint_names{iKeypoint}]));
                        end
                        hold off
                    end
                 end
            end
        end

        function overlay_interactive_keypoint_names(app)
            if (app.Camera01CheckBox.Value ...
                    || app.Camera02CheckBox.Value) 
                if app.Camera01CheckBox.Value
                    iCamera = 1;
                else
                    iCamera = 2;
                end
                camera_name = app.config.camera.camera_names{iCamera};
                if isfield(app.figure_handles,[camera_name,'_set_keypoints'])
                    if isvalid(app.figure_handles.([camera_name,'_set_keypoints']))
                        keypoints = app.figure_handles.([camera_name,'_set_keypoints']).Children.Children;
                        keypoints = keypoints(1:end-1);
                        if app.KeypointNamesCheckBox.Value
                            label_visible = 'on';
                        else
                            label_visible = 'hover';
                        end
                        for iKeypoint = 1:numel(keypoints)
                            keypoints(iKeypoint).LabelVisible = label_visible;
                        end
                    end
                end
            end
        end

        function plot_camera_frames(app)
            app.figure_handles.camera_frames = figure;
            hold on
            plot(app.time, app.camera_data.camera_frames)
            plot([app.current_time, app.current_time], [-5,5], 'k')
            hold off
            xlabel('Time (s)')
            ylabel('Voltage (V)')
            set(gca,'color','none')
            ylim([-.2,4])
            set(app.figure_handles.camera_frames, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Timestamps', ...
                'Position', double(app.config.display.('pos_camera_frames')));
        end

        function plot_treadmill_velocity(app)
            if isfield(app.figure_handles,'treadmill_velocity')
                if isvalid(app.figure_handles.treadmill_velocity)
                    close(app.figure_handles.treadmill_velocity)
                end
            end
            app.figure_handles.treadmill_velocity = figure;
            ax1 = subplot(311);
                hold on
                plot(app.time, app.treadmill_data.x_velocity)
                plot([app.current_time, app.current_time], [-30,30], 'k')
                hold off
                ylim([-25,25])
                ylabel('Forward (mm/s)')
                set(gca,'color','none','XTickLabel',[])
            ax2 = subplot(312);
                hold on
                plot(app.time, app.treadmill_data.y_velocity)
                plot([app.current_time, app.current_time], [-30,30], 'k')
                hold off
                ylim([-25,25])
                ylabel('Left side (mm/s)')
                set(gca,'color','none','XTickLabel',[])
            ax3 = subplot(313);
                hold on
                plot(app.time, app.treadmill_data.z_velocity)
                plot([app.current_time, app.current_time], [-500,500], 'k')
                hold off
                ylim([-500,500])
                xlabel('Time (s)')
                ylabel('Left turning (deg/s)')
                set(gca,'color','none')
            set(app.figure_handles.treadmill_velocity, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Treadmill velocity', ...
                'Position', double(app.config.display.('pos_treadmill_velocity')));
            linkaxes([ax1 ax2 ax3],'x')
        end

        function plot_treadmill_path(app)
            app.figure_handles.treadmill_path = figure;
            hold on
            plot(app.treadmill_data.y_path, app.treadmill_data.x_path)
            triangle_x = [0, 0.15, -0.15];
            triangle_y = [0.3, -0.3, -0.3];
            position = [app.treadmill_data.y_path(app.current_frame), ...
                        app.treadmill_data.x_path(app.current_frame)]; 
            h_patch = patch(triangle_x + position(1), ...
                triangle_y + position(2), ...
                [1,1,1], ...
                'EdgeColor', [0,0,0],...
                'LineWidth', 1,...
                'FaceColor', [0,0,0]);
            rotate(h_patch, ...
                [0 0 1], ...
                rad2deg(app.treadmill_data.heading(app.current_frame)), ...
                [position 0])

            hold off
            xlabel('Y (mm)')
            ylabel('X (mm)')
            set(gca,'color','none','XDir','reverse')
            axis equal
            grid on
            set(app.figure_handles.treadmill_path, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Treadmill path', ...
                'Position', double(app.config.display.('pos_treadmill_path')));
        end
        
        function plot_keypoint_data(app)
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_data_',camera_name])
                    % Close figure if open
                    if isfield(app.figure_handles,[camera_name,'_keypoints'])
                        if isvalid(app.figure_handles.([camera_name,'_keypoints']))
                            close(app.figure_handles.([camera_name,'_keypoints']))
                        end
                    end

                    camera_orientation = app.config.camera.camera_orientations{iCamera};
                    if strcmp(camera_orientation,'left')
                        label = 'L';
                    else
                        label = 'R';
                    end
                    
                    app.figure_handles.([camera_name,'_keypoints']) = figure;
                    hold on    
                    for iLeg = 1:3
                        ax(iLeg) = subplot(3,1,iLeg);
                        hold on
                        leg_vector_velocity = app.camera_data.([label,num2str(iLeg),'_leg_vector_velocity']);
                        plot(app.time, leg_vector_velocity, ...
                            'Color', app.config.display.(['color_I',num2str(iLeg),'A']));
                        
                        onsets = app.classification_data.([label,num2str(iLeg),'_swing_onsets']);
                        offsets = app.classification_data.([label,num2str(iLeg),'_swing_offsets']);
                        plot(app.time(onsets),leg_vector_velocity(onsets),'og')
                        plot(app.time(offsets),leg_vector_velocity(offsets),'om')
                        
                        plot([app.current_time, app.current_time], [-5,5], 'k')
                        hold off
                        ylim([-5,5])
                        set(gca,'color','none')
                        if iLeg~=3
                            set(gca,'XTickLabel',[])
                        end
                    end
                    xlabel('Time (s)')
                    set(gcf, ...
                        'menubar','none', ...
                        'toolbar','figure',...
                        'NumberTitle','off', ...
                        'Name',[camera_name,' keypoint data'], ...
                        'Position', double(app.config.display.(['pos_',camera_name,'_keypoints'])));
                    linkaxes([ax(1),ax(2),ax(3)],'x')
                end
            end
        end

        function plot_stimulus(app)
            % Close figure if open
            if isfield(app.figure_handles,'stimulus')
                if isvalid(app.figure_handles.stimulus)
                    close(app.figure_handles.stimulus)
                end
            end

            app.figure_handles.stimulus = figure;
            hold on
            plot(app.time, app.stimulus_data)
            plot([app.current_time, app.current_time], [-11,11], 'k')
            hold off
            xlabel('Time (s)')
            ylabel('Voltage (V)')
            set(gca,'color','none')
            ylim([-10.5,10.5])
            set(app.figure_handles.stimulus, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Stimulus', ...
                'Position', double(app.config.display.('pos_stimulus')));
        end

        function plot_behavior_classification(app)
            % Close figure if open
            if isfield(app.figure_handles,'behavior_classification')
                if isvalid(app.figure_handles.behavior_classification)
                    close(app.figure_handles.behavior_classification)
                end
            end

            % Flip classifications for figure
            classifications = fliplr({'Analyze','Resting','Walking', ...
                'Grooming T1','Grooming T3','Pushing','Other','Flight', ...
                'Annotation'});
            behavior = fliplr(app.classification_data.behavior);
            proofread_behavior = fliplr(app.classification_data.proofread_behavior);
            app.figure_handles.behavior_classification = figure;
            hold on
            for iClassification = 1:numel(classifications)
                y_offset = iClassification*2;
                plot(app.time, behavior(:,iClassification)+y_offset, ...
                    'Color', [0 0.4470 0.7410], 'LineStyle', ':')
                plot(app.time, proofread_behavior(:,iClassification)+y_offset, ...
                    'Color', [0 0.4470 0.7410], 'LineStyle', '-')
            end
            plot([app.current_time, app.current_time], [1,numel(classifications)*2+2], 'k')
            hold off
            xlabel('Time (s)')
            ylabel('Behavior')
            set(gca,'color','none','YTick',2:2:numel(classifications)*2,'YTickLabel',classifications,'TickLabelInterpreter','none')
            ylim([1,numel(classifications)*2+2])
            set(app.figure_handles.behavior_classification, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Behavior classification', ...
                'Position', double(app.config.display.('pos_behavior_classification')));
        end

        function plot_swing_classification(app)
            % Close figure if open
            if isfield(app.figure_handles,'swing_classification')
                if isvalid(app.figure_handles.swing_classification)
                    close(app.figure_handles.swing_classification)
                end
            end

            % Flip classifications for figure
            classifications = fliplr({'L1','L2','L3', ...
                'R1','R2','R3'});
            swing = fliplr(app.classification_data.swing);
            proofread_swing = fliplr(app.classification_data.proofread_swing);
            app.figure_handles.swing_classification = figure;
            hold on
            for iClassification = 1:numel(classifications)
                y_offset = iClassification*2;
                plot(app.time, swing(:,iClassification)+y_offset, ...
                    'Color', [0 0.4470 0.7410], 'LineStyle', ':')
                plot(app.time, proofread_swing(:,iClassification)+y_offset, ...
                    'Color', [0 0.4470 0.7410], 'LineStyle', '-')
            end
            plot([app.current_time, app.current_time], [1,numel(classifications)*2+2], 'k')
            hold off
            xlabel('Time (s)')
            ylabel('Swing')
            set(gca,'color','none','YTick',2:2:numel(classifications)*2,'YTickLabel',classifications,'TickLabelInterpreter','none')
            ylim([1,numel(classifications)*2+2])
            set(app.figure_handles.swing_classification, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Swing classification', ...
                'Position', double(app.config.display.('pos_swing_classification')));

            % % Close figure if open
            % if isfield(app.figure_handles,'swing_classification')
            %     if isvalid(app.figure_handles.swing_classification)
            %         close(app.figure_handles.swing_classification)
            %     end
            % end
            % 
            % classifications = {'L1_swing_onsets', 'L1_swing_offsets'; ...
            %     'L2_swing_onsets', 'L2_swing_offsets'; ...
            %     'L3_swing_onsets', 'L3_swing_offsets'; ...
            %     'R1_swing_onsets', 'R1_swing_offsets'; ...
            %     'R2_swing_onsets', 'R2_swing_offsets'; ...
            %     'R3_swing_onsets', 'R3_swing_offsets'};
            % colors = [app.config.display.color_I1E; app.config.display.color_I2E; app.config.display.color_I3E; ...
            %     app.config.display.color_I1E; app.config.display.color_I2E; app.config.display.color_I3E];
            % app.figure_handles.swing_classification = figure;
            % hold on
            % for iClassification = 1:size(classifications,1)
            %     y_offset = iClassification*2;
            %     % Swing onsets
            %     plot(app.time(app.classification_data.(classifications{iClassification,1})), ...
            %         app.classification_data.(classifications{iClassification,1})+y_offset, ...
            %         'Color', colors(iClassification,:))
            %     % Swing offsets
            %     plot(app.time(app.classification_data.(classifications{iClassification,2})), ...
            %         app.classification_data.(classifications{iClassification,2})+y_offset, ...
            %         'Color', colors(iClassification,:))
            % end
            % plot([app.current_time, app.current_time], [1,numel(classifications)*2+2], 'k')
            % hold off
            % xlabel('Time (s)')
            % ylabel('Swing')
            % set(gca,'color','none','YTick',2:2:numel(classifications)*2,'YTickLabel',classifications,'TickLabelInterpreter','none')
            % ylim([1,numel(classifications)*2+2])
            % set(app.figure_handles.swing_classification, ...
            %     'menubar','none', ...
            %     'toolbar','figure',...
            %     'NumberTitle','off', ...
            %     'Name', 'Swing classification', ...
            %     'Position', double(app.config.display.('pos_swing_classification')));
        end

        function update_time(app)
            app.current_time = app.time(app.current_frame);
            app.TimeLabel.Text = string(app.current_time);
        end

        function update_figures(app)
            % Update video plots 
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if isfield(app.figure_handles,camera_name) ...
                        && isvalid(app.figure_handles.(camera_name))
                    % Update CData
                    app.figure_handles.(camera_name).CurrentAxes.Children(end).CData = ...
                        app.camera_data.(['vid_data_',camera_name])(app.current_frame).cdata;
                    % Update overlay
                    if app.config.camera.(['is_data_',camera_name]) && app.KeypointsCheckBox.Value
                        handles = app.figure_handles.(camera_name).Children.Children;
                        figure(app.figure_handles.(camera_name))
                        
                        % Update leg vectors 
                        camera_orientation = app.config.camera.camera_orientations{iCamera};
                        if strcmp(camera_orientation,'left')
                            label = 'L';
                        else
                            label = 'R';
                        end
                        for iLeg = 1:3
                            leg_vector_video_position = app.camera_data.([label,num2str(iLeg),'_leg_vector_video_position'])(app.current_frame,:);
                            handles(iLeg).XData = [leg_vector_video_position(1), leg_vector_video_position(2)];
                            handles(iLeg).YData = [leg_vector_video_position(3), leg_vector_video_position(4)];
                        end
                    
                        % Update keypoints
                        handles = handles(4:end-1); % Exclude video image
                        handles = flipud(handles); % Invert order
                        for iKeypoint = 1:numel(handles)
                            keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                            handles(iKeypoint).XData = app.camera_data.(['data_',camera_name,'_filtered'])(app.current_frame,keypoint_indices(1));
                            handles(iKeypoint).YData = app.camera_data.(['data_',camera_name,'_filtered'])(app.current_frame,keypoint_indices(2));
                        end
                    end
                end
            end

            % Update camera frames plot
            if isfield(app.figure_handles,'camera_frames')
                if isvalid(app.figure_handles.camera_frames)
                    app.figure_handles.camera_frames.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end

            % Update treadmill velocity plot
            if isfield(app.figure_handles,'treadmill_velocity')
                if isvalid(app.figure_handles.treadmill_velocity)
                    h_subplots = app.figure_handles.treadmill_velocity.Children;
                    for iSubplot = 1:numel(h_subplots)
                        h_subplots(iSubplot).Children(1).XData = ...
                            [app.current_time, app.current_time];
                    end
                end
            end

            % Update treadmill path plot
            if isfield(app.figure_handles,'treadmill_path')
                if isvalid(app.figure_handles.treadmill_path)
                    % Update position
                    triangle_x = [0, 0.15, -0.15];
                    triangle_y = [0.3, -0.3, -0.3];
                    position = [app.treadmill_data.y_path(app.current_frame), ...
                        app.treadmill_data.x_path(app.current_frame)];
                    h_patch = app.figure_handles.treadmill_path.Children.Children(1);
                    h_patch.XData = triangle_x + position(1); 
                    h_patch.YData = triangle_y + position(2);
                    % Update rotation
                    rotate(h_patch, ...
                        [0 0 1], ...
                        rad2deg(app.treadmill_data.heading(app.current_frame)), ...
                        [position 0])
                end
            end

            % Update keypoints plot
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if isfield(app.figure_handles,[camera_name,'_keypoints'])
                    if isvalid(app.figure_handles.([camera_name,'_keypoints']))
                        app.figure_handles.([camera_name,'_keypoints']).Children(1).Children(1).XData = ...
                            [app.current_time, app.current_time];
                        app.figure_handles.([camera_name,'_keypoints']).Children(2).Children(1).XData = ...
                            [app.current_time, app.current_time];
                        app.figure_handles.([camera_name,'_keypoints']).Children(3).Children(1).XData = ...
                            [app.current_time, app.current_time];
                    end
                end
            end
            
            % Update stimulus plot
            if isfield(app.figure_handles,'stimulus')
                if isvalid(app.figure_handles.stimulus)
                    app.figure_handles.stimulus.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end

            % Update behavior classification plot
            if isfield(app.figure_handles,'behavior_classification')
                if isvalid(app.figure_handles.behavior_classification)
                    app.figure_handles.behavior_classification.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end

            % Update swing classification plot
            if isfield(app.figure_handles,'swing_classification')
                if isvalid(app.figure_handles.swing_classification)
                    app.figure_handles.swing_classification.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FrameLabel.Text = "";
            app.TimeLabel.Text = "";
            app.TrialLabel.Text = "";
            app.KeypointsCheckBox.Value = true;
            app.StartFrameEditFieldBehavior.Value = 0;
            app.EndFrameEditFieldBehavior.Value = 0;

            % Reset private properties except for config
            mc = ?fly_on_the_ball_analysis_app;
            property_list = mc.PropertyList;
            for iProperty = 1:numel(property_list)
                if all(strcmp(property_list(iProperty).GetAccess,'private')) ...
                        && ~strcmp(property_list(iProperty).Name,'config')
                    app.(property_list(iProperty).Name) = [];
                end
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            startupFcn(app)
            
            % If config already exists, get previous experimental folder
            previous_folder = [];
            if ~isempty(app.config) 
                if isfield(app.config,'experiment')
                    previous_folder = [app.config.dir.data, app.config.experiment];
                end
            end
            
            % Load configuration file
            path_config = 'config.toml';
            config = toml.read(path_config);
            app.config = toml.map_to_struct(config);

            % Set directory
            app.config.dir.data = app.config.dir.daq_data;
                
            if isempty(previous_folder)
                [app.config.file_name,~] = uigetfile(...
                    {'*.*'},...
                    'Select mat file',...
                    app.config.dir.data);
                if app.config.file_name == 0
                    return
                end
            else
                [app.config.file_name,~] = uigetfile(...
                    {'*.*'},...
                    'Select mat file',...
                    previous_folder);
                if app.config.file_name == 0
                    return
                end
            end

            app.config.trial_name = app.config.file_name(1:end-4); 
            app.config.experiment = app.config.file_name(1:end-8);
            
            clc
            disp(['Loading ',app.config.trial_name,'...'])

            % Use the camera sampling rate as the reference sampling rate
            app.config.reference_sampling_rate = app.config.camera.sampling_rate;
            
            % Load DAQ data
            load([app.config.dir.data, ...
                app.config.experiment,'/', ...
                app.config.file_name]);
            app.daq_data = trial_data;

            % Process DAQ data
            process_daq_data(app)

            % Check if videos are available
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                app.config.camera.(['is_',camera_name]) = false;
                if exist( ...
                        [app.config.dir.data, ...
                        app.config.experiment, ...
                        app.config.camera.folder_videos, ...
                        app.config.trial_name,'_', ...
                        camera_name,'.mp4'], ...
                        'file') == 2
            
                    app.config.camera.(['is_',camera_name]) = true;
                end
                clearvars camera_name
            end

            % Check if leg tracking data are available 
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                app.config.camera.(['is_data_',camera_name]) = false;
                if exist( ...
                        [app.config.dir.data, ...
                         app.config.experiment, ...
                         app.config.camera.folder_videos, ...
                         app.config.trial_name, '_',...
                         camera_name, ...
                         app.config.deeplabcut.model_name,'.h5'],...
                        'file') == 2
                    
                    app.config.camera.(['is_data_',camera_name]) = true;
                end
            end

            % Initialize videos 
            % 
            % Note: For mp4, loading video data frame by frame in the
            % update function above rather than here would be much slower,
            % particularly when playing the video backwards. This is
            % because mp4 files are not indexed by frame number, but by
            % frame time, and they are potentially variable-rate. When
            % moving forward, the internal code needs to keep a buffer of
            % the current group of frames. When moving backward, the
            % internal code discards the buffer and moves forward from the
            % beginning, which takes time. See also
            % https://www.mathworks.com/matlabcentral/answers/704167-videoreader-reads-frames-significantly-faster-forward-than-backwards.
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_',camera_name])
                    app.camera_data.(['vid_obj_',app.config.camera.camera_names{iCamera}]) = VideoReader( ...
                        [app.config.dir.data, ...
                         app.config.experiment, ...
                         app.config.camera.folder_videos, ...
                         app.config.trial_name,'_', ...
                         camera_name,'.mp4']);
                    
                    % Load video data
                    % Note: readFrame() is faster than read()
                    disp(['Loading ',camera_name,' data...'])
                    vid_obj = app.camera_data.(['vid_obj_',app.config.camera.camera_names{iCamera}]);
                    app.camera_data.(['vid_data_',camera_name]) = ...
                        struct('cdata', zeros(vid_obj.Height,vid_obj.Width,3,'uint8'), ...
                        'colormap', []);
                    k = 1;
                    while hasFrame(vid_obj)
                        app.camera_data.(['vid_data_',camera_name])(k).cdata = readFrame(vid_obj);
                        k = k+1;
                    end
                    disp('Done!')         
                end
                clearvars camera_name
            end
            
            app.current_frame = 1;
            app.current_time = 0;
            % Because the cameras continue recording for a few frames after
            % the end of the session, calculate the last available frame
            % based on the duration of the session
            app.config.last_frame = length(app.time); 
            app.FramesSlider.Limits = [1, app.config.last_frame];
            app.FramesSlider.Value = 1;
            app.ReplaySpeedSlider.Limits = [1, app.config.last_frame/double(app.config.reference_sampling_rate)*3];
            app.TrialLabel.Text = app.config.trial_name;
            app.FrameLabel.Text = string(app.current_frame);
            app.TimeLabel.Text = string(app.current_time);

            is_camera_data = app.config.camera.(['is_data_',app.config.camera.camera_names{1}]);

            % Initialize behavior classification data
            % Analyze, resting, walking, grooming with front legs, 
            % grooming with hind legs, ball pushing, other leg movements, 
            % flight, annotation 
            app.classification_data.behavior = zeros(app.config.last_frame,9);
            app.classification_data.behavior(:,1) = 1;

            % Check if proofread behavior classification is available
            path_to_csv = [app.config.dir.data, ...
                app.config.experiment, ...
                app.config.dir.classification_data, ...
                app.config.trial_name, ...
                '_behavior_classification.csv'];
            if exist(path_to_csv, 'file') == 2
                app.classification_data.proofread_behavior = csvread(path_to_csv);
            end
                     
            % Initialize swing classification data
            app.classification_data.swing = zeros(app.config.last_frame,6);
            
            % Check if proofread swing classification is available
            path_to_csv = [app.config.dir.data, ...
                app.config.experiment, ...
                app.config.dir.classification_data, ...
                app.config.trial_name, ...
                '_swing_classification.csv'];
            if exist(path_to_csv, 'file') == 2
                app.classification_data.proofread_swing = csvread(path_to_csv);
            end


            % Process data
            process_treadmill_data(app)
            if is_camera_data
                process_deeplabcut_data(app)
                classify_behavior(app)
                classify_swing(app)
            end

            % Plot data
            plot_camera(app) 
            if app.KeypointsCheckBox.Value
                overlay_keypoints(app)
            end
            plot_treadmill_velocity(app)
            plot_treadmill_path(app)
            plot_behavior_classification(app)
            if is_camera_data
                plot_keypoint_data(app)
                plot_swing_classification(app)
            end
            plot_stimulus(app)
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            close all
        end

        % Menu selected function: SaveClassificationMenu
        function SaveClassificationMenuSelected(app, event)
            save_path = strcat( ...
                app.config.dir.data, ...
                app.config.experiment, ...
                app.config.dir.classification_data, ...
                app.config.trial_name, ...
                '_behavior_classification.csv');
            
            writematrix(app.classification_data.proofread_behavior, save_path)
            disp(['Behavior classification saved as ',convertStringsToChars(save_path)])

            save_path = strcat( ...
                app.config.dir.data, ...
                app.config.experiment, ...
                app.config.dir.classification_data, ...
                app.config.trial_name, ...
                '_swing_classification.csv');
            
            writematrix(app.classification_data.proofread_swing, save_path)
            disp(['Swing classification saved as ',convertStringsToChars(save_path)])
        end

        % Menu selected function: SaveDisplayMenu
        function SaveDisplayMenuSelected(app, event)
            % Load configuration file
            path_config = 'config.toml';
            config = toml.read(path_config);
            config = toml.map_to_struct(config);

            figure_names = fieldnames(app.figure_handles);
            for iFigure = 1:numel(figure_names)
                if isvalid(app.figure_handles.(figure_names{iFigure}))
                    pos = app.figure_handles.(figure_names{iFigure}).Position;
                    config.display.(['pos_',(figure_names{iFigure})]) = int64(pos);
                end
            end
            toml.write('config.toml',config)
            disp('Display options saved to config.toml')
        end

        % Menu selected function: SaveVideoMenu
        function SaveVideoMenuSelected(app, event)
            % To do: Generalize
            save_dir = 'C:/Users/Chris/Desktop/2024-06-06_A02_007_350-850_0-1x.mp4';
            start_frame = 350; 
            end_frame = 850; 
            sampling_rate = 30;
            replay_speed = 0.1; %0.1; 
            iCamera = 1;

            interval_frame = double(app.config.reference_sampling_rate)/(sampling_rate/replay_speed);
            save_frames = start_frame:interval_frame:end_frame;
             
            stimulus_frames = app.stimulus_data;
            time_frames = app.time;
            % Express time relative to stimulus onset
            time_frames = time_frames - time_frames(find(stimulus_frames>-8,1,'first'));
            
            camera_name = app.config.camera.camera_names{iCamera};

            f = figure;
            f.Position = [100, 100, ...
                app.camera_data.(['vid_obj_',camera_name]).Width, ...
                app.camera_data.(['vid_obj_',camera_name]).Height + round(app.camera_data.(['vid_obj_',camera_name]).Height*1/3)];
            n_frame = 0;
            for iFrame = save_frames
                n_frame = n_frame+1;
                figure(f), clf

                % Plot video
                subplot(3,2,1:4)
                vid_data = read(app.camera_data.(['vid_obj_',camera_name]),iFrame);
                imshow(vid_data,'border','tight');
                % set(f, ...
                %     'menubar','none', ...
                %     'NumberTitle','off', ...
                %     'Name', camera_name, ...
                %     'Position', double(app.config.display.(['pos_',camera_name])));

                % text(850,60,'slowed 10x', ...
                %     'FontName', 'Arial', 'FontSize', 18, ...
                %     'Color', [1,1,1], ...
                %     'HorizontalAlignment', 'right')
                
                % Add time
                time_str = num2str(time_frames(iFrame),'%3.2f');
                text(850,60,[time_str,' s'], ...
                    'FontName', 'Arial', 'FontSize', 20, ... %16
                    'Color', [1,1,1], ...
                    'HorizontalAlignment', 'right')
                
                % Overlay stimulus indicator
                if stimulus_frames(iFrame) > -8 
                    hold on
                    rectangle('Position',[100,40,40,40],'FaceColor','w')
                end


                % % Overlay swing phase detection
                % if any(app.classification_data.R1_swing_onsets == iFrame) 
                %     hold on
                %     %rectangle('Position',[190,450,40,40],'FaceColor',[0.08, 0.79, 0.89],'EdgeColor',[0.08, 0.79, 0.89])
                %     text(50,350,'LO', ...
                %         'FontName', 'Arial', 'FontSize', 16, ...
                %         'Color', [0.08, 0.79, 0.89], ...
                %         'HorizontalAlignment', 'left')
                % end
                % if any(app.classification_data.R1_swing_offsets == iFrame)
                %     hold on
                %     %rectangle('Position',[250,450,40,40],'FaceColor',[0.08, 0.79, 0.89],'EdgeColor',[0.08, 0.79, 0.89])
                %     text(50,350,'TD', ...
                %         'FontName', 'Arial', 'FontSize', 16, ...
                %         'Color', [0.08, 0.79, 0.89], ...
                %         'HorizontalAlignment', 'left')
                % end
                % if app.classification_data.L2_swing(iFrame) == 1
                %     hold on
                %     rectangle('Position',[500,450,40,40],'FaceColor',[0.46, 0.99, 0.72],'EdgeColor',[0.46, 0.99, 0.72])
                % end
                % if app.classification_data.L3_swing(iFrame) == 1
                %     hold on
                %     rectangle('Position',[650,450,40,40],'FaceColor',[0.83, 0.86, 0.5],'EdgeColor',[0.83, 0.86, 0.5])
                % end

                if app.config.camera.(['is_',camera_name])
                    labels = app.config.deeplabcut.keypoint_names; % To do: Update
                    labels = labels(1:end-8);
                    figure(f)
                    hold on
                    for iKeypoint = 1:numel(labels)
                        keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                        plot(...
                            app.camera_data.(['data_',camera_name,'_filtered'])(iFrame,keypoint_indices(1)), ...
                            app.camera_data.(['data_',camera_name,'_filtered'])(iFrame,keypoint_indices(2)), ...
                            'o', ...
                            'Color', app.config.display.(['color_',labels{iKeypoint}]), ...
                            'MarkerFaceColor', app.config.display.(['color_',labels{iKeypoint}]), ...
                            'MarkerSize', 10);
                    end
                    % % Overlay leg vectors
                    % leg_colors = [ ...
                    %     0.08, 0.79, 0.89; ...
                    %     0.46, 0.99, 0.72; ...
                    %     0.83, 0.86, 0.5];
                    % camera_orientation = app.config.camera.camera_orientations{iCamera};
                    % if strcmp(camera_orientation,'left')
                    %     label = 'L';
                    % else
                    %     label = 'R';
                    % end
                    % for iLeg = 1:3
                    %     leg_vector_video_position = app.camera_data.([label,num2str(iLeg),'_leg_vector_video_position'])(iFrame,:);
                    %     plot([leg_vector_video_position(1), leg_vector_video_position(2)], ...
                    %         [leg_vector_video_position(3), leg_vector_video_position(4)], ...
                    %         '-','Color',leg_colors(iLeg,:), 'LineWidth',1.5);
                    % end
                    hold off
                end

                % subplot(3,2,5:6)
                % patch_width = 0.5;
                % patch_y = [1,0.5,0];
                % leg_colors = [ ...
                %         0.08, 0.79, 0.89; ...
                %         0.46, 0.99, 0.72; ...
                %         0.83, 0.86, 0.5];
                % for iLeg = 1:3
                %     onsets = app.classification_data.(['L',num2str(iLeg),'_swing_onsets']);
                %     offsets = app.classification_data.(['L',num2str(iLeg),'_swing_offsets']);
                %     for iSwing = 1:numel(onsets)
                %         x = [onsets(iSwing), offsets(find(offsets>onsets(iSwing),1,'first'))];
                %         y = [patch_y(iLeg), patch_y(iLeg)+patch_width];
                %         patch([x(1), x(2), x(2), x(1)], [y(1), y(1), y(2), y(2)], ...
                %             leg_colors(iLeg,:), ...
                %             'EdgeColor','none')
                %         hold on
                %     end
                % end
                % plot([iFrame,iFrame], [0,1.5], ...
                %     '-','Color', [0, 0, 0], 'LineWidth', 1.5)
                % xlim([iFrame-19,iFrame+20])
                % set(gca,'Color','none')
                % axis off

                % subplot(3,2,6)
                % plot(app.time,app.camera_data.R1_leg_vector_velocity*-1 + 14, ...
                %     'Color', leg_colors(1,:), 'LineWidth', 1.5)
                % hold on
                % plot(app.time,app.camera_data.R2_leg_vector_velocity*-1 + 7, ...
                %     'Color', leg_colors(2,:), 'LineWidth', 1.5)
                % plot(app.time,app.camera_data.R3_leg_vector_velocity*-1, ...
                %     'Color', leg_colors(3,:), 'LineWidth', 1.5)
                % 
                % plot([app.time(iFrame),app.time(iFrame)], [-5,20], ...
                %     '-','Color', [0, 0, 0], 'LineWidth', 1.5)
                % hold off
                % % hold on
                % % plot(app.time(app.classification_data.R1_swing_onsets), ...
                % %     app.camera_data.R1_leg_vector_velocity(app.classification_data.R1_swing_onsets), ...
                % %     'square','MarkerEdgeColor',[0.08, 0.79, 0.89],'MarkerFaceColor',[0.08, 0.79, 0.89])
                % % hold off
                % xlim([app.time(iFrame-19),app.time(iFrame+20)])
                % ylim([-2.5,20])
                % set(gca,'Color','none')
                % axis off 

                %f.Position = [605 645 520 462];

                set(gcf,'Color',[1,1,1])

                F(n_frame) = getframe(gcf);
            end
            close(f)

            % Create a video writer object
            writer_obj = VideoWriter(save_dir,'MPEG-4');
            writer_obj.FrameRate = sampling_rate;
            open(writer_obj);
            % Write frames to video
            for iFrame = 1:length(F)
                % Convert image to frame
                frame = F(iFrame);
                writeVideo(writer_obj, frame);
            end
            clearvars F
            % Close the video writer object
            close(writer_obj);
            disp(['Video saved as ',save_dir])
        end

        % Menu selected function: CamerasMenu
        function CamerasMenuSelected(app, event)
            plot_camera(app)
        end

        % Menu selected function: VelocityMenu
        function VelocityMenuSelected(app, event)
            plot_treadmill_velocity(app)
        end

        % Menu selected function: PathMenu
        function PathMenuSelected(app, event)
            plot_treadmill_path(app)
        end

        % Menu selected function: StimulusMenu
        function StimulusMenuSelected(app, event)
            plot_stimulus(app)
        end

        % Menu selected function: CameraFramesMenu
        function CameraFramesMenuSelected(app, event)
            plot_camera_frames(app)
        end

        % Menu selected function: KeypointsMenu
        function KeypointsMenuSelected(app, event)
            plot_keypoint_data(app)
        end

        % Menu selected function: BehaviorClassificationMenu
        function BehaviorClassificationMenuSelected(app, event)
            plot_behavior_classification(app)
        end

        % Menu selected function: SwingClassificationMenu
        function SwingClassificationMenuSelected(app, event)
            % plot_swing_classification(app)
        end

        % Button pushed function: FwdButton
        function FwdButtonPushed(app, event)
            if strcmp(app.FwdButton.Text,'Fwd')
                app.FwdButton.Text = 'Stop';
                app.RwdButton.Text = 'Rwd';
            else
                app.FwdButton.Text = 'Fwd';
            end

            % Loop through frames
            replay_frames = round(app.ReplaySpeedSlider.Value); 
            while app.current_frame <= app.config.last_frame - replay_frames
                if strcmp(app.FwdButton.Text,'Fwd')
                    break;
                end
                app.current_frame = round(app.FramesSlider.Value) + replay_frames;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame); 
                update_time(app)
                update_figures(app)  
                drawnow
            end
            if app.current_frame > app.config.last_frame - replay_frames
                app.current_frame = 1;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                app.FwdButton.Text = 'Fwd';
                update_time(app)
                update_figures(app)  
                drawnow
            end
        end

        % Button pushed function: RwdButton
        function RwdButtonPushed(app, event)
            if strcmp(app.RwdButton.Text,'Rwd')
                app.RwdButton.Text = 'Stop';
            else
                app.RwdButton.Text = 'Rwd';
            end

            % Loop through frames
            replay_frames = round(app.ReplaySpeedSlider.Value); 
            while app.current_frame >= 1 + replay_frames
                if strcmp(app.RwdButton.Text,'Rwd')
                    break;
                end
                app.current_frame = round(app.FramesSlider.Value) - replay_frames;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                update_time(app)
                update_figures(app)
                drawnow
            end
            if app.current_frame < 1 + replay_frames
                app.current_frame = 1;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                app.RwdButton.Text = 'Rwd';
                update_time(app)
                update_figures(app)  
                drawnow
            end
        end

        % Button pushed function: SingleFwdButton
        function SingleFwdButtonPushed(app, event)
            value = round(app.FramesSlider.Value);
            value = value+1;
            if value>app.config.last_frame
                value = app.config.last_frame;
            end
            app.current_frame = value;
            app.FramesSlider.Value = value;
            app.FrameLabel.Text = string(value);

            update_time(app)
            update_figures(app)
            drawnow
        end

        % Button pushed function: SingleRwdButton
        function SingleRwdButtonPushed(app, event)
            value = round(app.FramesSlider.Value);
            value = value-1;
            if value<1
                value = 1;
            end
            app.current_frame = value;
            app.FramesSlider.Value = value;
            app.FrameLabel.Text = string(value);
            
            update_time(app)
            update_figures(app)
            drawnow
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            value = round(app.FramesSlider.Value);
            app.current_frame = value;
            app.FrameLabel.Text = string(value);
                
            update_time(app)
            update_figures(app)
            drawnow
        end

        % Button pushed function: AdjustDisplayButton
        function AdjustDisplayButtonPushed(app, event)
            plot_camera(app)
            if app.KeypointsCheckBox.Value
                overlay_keypoints(app)
            end
        end

        % Value changed function: AnalyzeCheckBox
        function AnalyzeCheckBoxChanged(app, event)
            value = app.AnalyzeCheckBox.Value;
            if value
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.PushingCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
            end
        end

        % Value changed function: RestingCheckBox
        function RestingCheckBoxChanged(app, event)
            value = app.RestingCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.PushingCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
            end
        end

        % Value changed function: WalkingCheckBox
        function WalkingCheckBoxChanged(app, event)
            value = app.WalkingCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.PushingCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
            end
        end

        % Value changed function: FLgroomingCheckBox
        function FLgroomingCheckBoxChanged(app, event)
            value = app.FLgroomingCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.PushingCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
            end
        end

        % Value changed function: HLgroomingCheckBox
        function HLgroomingCheckBoxChanged(app, event)
            value = app.HLgroomingCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.PushingCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
            end
        end

        % Value changed function: PushingCheckBox
        function PushingCheckBoxChanged(app, event)
            value = app.PushingCheckBox.Value;
            if value
               app.AnalyzeCheckBox.Value = false;
               app.RestingCheckBox.Value = false;
               app.WalkingCheckBox.Value = false;
               app.FLgroomingCheckBox.Value = false;
               app.HLgroomingCheckBox.Value = false;
               app.OtherCheckBox.Value = false;
               app.FlightCheckBox.Value = false;
            end
            
        end

        % Value changed function: OtherCheckBox
        function OtherCheckBoxChanged(app, event)
            value = app.OtherCheckBox.Value;
             if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.PushingCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
            end
        end

        % Value changed function: FlightCheckBox
        function FlightCheckBoxChanged(app, event)
            value = app.FlightCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.PushingCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
            end
        end

        % Button pushed function: StartFrameButtonBehavior
        function StartFrameButtonBehaviorPushed(app, event)
            % Get cursor position
            dcm_obj = datacursormode(app.figure_handles.behavior_classification);
            dcinfo = getCursorInfo(dcm_obj);
            if ~isempty(dcinfo)
                app.StartFrameEditFieldBehavior.Value = dcinfo.DataIndex;
            else            
                app.StartFrameEditFieldBehavior.Value = app.current_frame;
            end
        end

        % Button pushed function: EndFrameButtonBehavior
        function EndFrameButtonBehaviorPushed(app, event)
            % Get cursor position
            dcm_obj = datacursormode(app.figure_handles.behavior_classification);
            dcinfo = getCursorInfo(dcm_obj);
            if ~isempty(dcinfo)
                app.EndFrameEditFieldBehavior.Value = dcinfo.DataIndex;
            else            
                app.EndFrameEditFieldBehavior.Value = app.current_frame;
            end
        end

        % Button pushed function: SetToOneButtonBehavior
        function SetToOneButtonBehaviorPushed(app, event)
            col = [];
            if app.AnalyzeCheckBox.Value 
                col = 1;
            elseif app.RestingCheckBox.Value
                col = 2;
            elseif app.WalkingCheckBox.Value
                col = 3;
            elseif app.FLgroomingCheckBox.Value
                col = 4;   
            elseif app.HLgroomingCheckBox.Value
                col = 5; 
             elseif app.PushingCheckBox.Value
                col = 6; 
            elseif app.OtherCheckBox.Value
                col = 7; 
            elseif app.FlightCheckBox.Value
                col = 8; 
            end

            cols_to_set_automatically = 2:8;
            if ~isempty(col) ...
                    && ~isempty(app.StartFrameEditFieldBehavior.Value) ...
                    && ~isempty(app.EndFrameEditFieldBehavior.Value)
                app.classification_data.proofread_behavior(app.StartFrameEditFieldBehavior.Value:app.EndFrameEditFieldBehavior.Value, col) = 1;
                % Set other behaviors to zero
                app.classification_data.proofread_behavior(app.StartFrameEditFieldBehavior.Value:app.EndFrameEditFieldBehavior.Value, cols_to_set_automatically(cols_to_set_automatically~=col)) = 0;
                plot_behavior_classification(app)
            end
        end

        % Button pushed function: SetToZeroButtonBehavior
        function SetToZeroButtonBehaviorPushed(app, event)
            col = [];
            if app.AnalyzeCheckBox.Value 
                col = 1;
            elseif app.RestingCheckBox.Value
                col = 2;
            elseif app.WalkingCheckBox.Value
                col = 3;
            elseif app.FLgroomingCheckBox.Value
                col = 4;   
            elseif app.HLgroomingCheckBox.Value
                col = 5; 
             elseif app.PushingCheckBox.Value
                col = 6; 
            elseif app.OtherCheckBox.Value
                col = 7; 
            elseif app.FlightCheckBox.Value
                col = 8; 
            end

            if ~isempty(col) ...
                    && ~isempty(app.StartFrameEditFieldBehavior.Value) ...
                    && ~isempty(app.EndFrameEditFieldBehavior.Value)
                app.classification_data.proofread_behavior(app.StartFrameEditFieldBehavior.Value:app.EndFrameEditFieldBehavior.Value, col) = 0;
                plot_behavior_classification(app)
            end
        end

        % Value changed function: Camera01CheckBox
        function Camera01CheckBoxSelected(app, event)
            value = app.Camera01CheckBox.Value;
            if value 
                app.Camera02CheckBox.Value = false;
            end
        end

        % Value changed function: Camera02CheckBox
        function Camera02CheckBoxSelected(app, event)
            value = app.Camera02CheckBox.Value;
            if value 
                app.Camera01CheckBox.Value = false;
            end
        end

        % Value changed function: KeypointNamesCheckBox
        function ShowKeypointNamesChanged(app, event)
            overlay_interactive_keypoint_names(app)
        end

        % Button pushed function: AdjustKeypointsButton
        function AdjustKeypointsButtonPushed(app, event)
            overlay_interactive_keypoints(app)
            if app.KeypointNamesCheckBox.Value
                overlay_interactive_keypoint_names(app)
            end
        end

        % Button pushed function: ExportKeypointsButton
        function ExportKeypointsButtonPushed(app, event)
            x_ignore = 50;
            if app.Camera01CheckBox.Value
                iCamera = 1; 
            else
                iCamera = 2;
            end
            camera_name = app.config.camera.camera_names{iCamera};
            keypoints = app.figure_handles.([camera_name,'_set_keypoints']).Children.Children;
            keypoints = keypoints(1:end-1); % Exclude video image
            keypoints = flipud(keypoints); % Invert order
            png_name = ['img',sprintf('%05d',str2double(app.FrameLabel.Text)),'.png'];
            labeled_data = [];
            col = 1;
            for iKeypoint = 1:numel(keypoints)
                if keypoints(iKeypoint).Position(1) > x_ignore
                    labeled_data(col) = keypoints(iKeypoint).Position(1); % X coordinate
                    labeled_data(col+1) = keypoints(iKeypoint).Position(2); % Y coordinate
                else               
                    labeled_data(col) = missing;
                    labeled_data(col+1) = missing;
                end
                col = col+2;
            end
            % Save mat-file 
            % Python starts indexing at 0, Matlab at 1. Keypoint
            % coordinates are corrected later when writing csv file for
            % DeepLabCut.
            path_to_folder = [app.config.dir.deeplabcut_training_data,app.config.trial_name,'/'];
            if ~exist(path_to_folder, 'dir')
                mkdir(path_to_folder)
            end
            save([path_to_folder, app.config.trial_name,'_',camera_name,'_',png_name(1:end-4),'.mat'],'labeled_data')
            % Plot video frame without labels and save as png
            vid_data = read(app.camera_data.(['vid_obj_',camera_name]),app.current_frame);
            figure
            imshow(vid_data,'border','tight');
            set(gcf, ...
                'menubar','none', ...
                'NumberTitle','off', ...
                'Name', camera_name, ...
                'Position', [200,200,size(vid_data,2),size(vid_data,1)]);
            export_fig([path_to_folder, app.config.trial_name,'_',camera_name,'_',png_name],gcf) % saveas() changes figure size 
            close(gcf)
        end

        % Value changed function: L1CheckBox
        function L1CheckBoxChanged(app, event)
            value = app.L1CheckBox.Value;
            if value
                app.L2CheckBox.Value = false;
                app.L3CheckBox.Value = false;
                app.R1CheckBox.Value = false;
                app.R2CheckBox.Value = false;
                app.R3CheckBox.Value = false;
                app.AllCheckBox.Value = false;
            end
        end

        % Value changed function: L2CheckBox
        function L2CheckBoxChanged(app, event)
            value = app.L2CheckBox.Value;
            if value
                app.L1CheckBox.Value = false;
                app.L3CheckBox.Value = false;
                app.R1CheckBox.Value = false;
                app.R2CheckBox.Value = false;
                app.R3CheckBox.Value = false;
                app.AllCheckBox.Value = false;
            end
        end

        % Value changed function: L3CheckBox
        function L3CheckBoxChanged(app, event)
            value = app.L3CheckBox.Value;
            if value
                app.L1CheckBox.Value = false;
                app.L2CheckBox.Value = false;
                app.R1CheckBox.Value = false;
                app.R2CheckBox.Value = false;
                app.R3CheckBox.Value = false;
                app.AllCheckBox.Value = false;
            end
        end

        % Value changed function: R1CheckBox
        function R1CheckBoxChanged(app, event)
            value = app.R1CheckBox.Value;
            if value
                app.L1CheckBox.Value = false;
                app.L2CheckBox.Value = false;
                app.L3CheckBox.Value = false;
                app.R2CheckBox.Value = false;
                app.R3CheckBox.Value = false;
                app.AllCheckBox.Value = false;
            end
        end

        % Value changed function: R2CheckBox
        function R2CheckBoxChanged(app, event)
            value = app.R2CheckBox.Value;
            if value
                app.L1CheckBox.Value = false;
                app.L2CheckBox.Value = false;
                app.L3CheckBox.Value = false;
                app.R1CheckBox.Value = false;
                app.R3CheckBox.Value = false;
                app.AllCheckBox.Value = false;
            end
        end

        % Value changed function: R3CheckBox
        function R3CheckBoxChanged(app, event)
            value = app.R3CheckBox.Value;
            if value
                app.L1CheckBox.Value = false;
                app.L2CheckBox.Value = false;
                app.L3CheckBox.Value = false;
                app.R1CheckBox.Value = false;
                app.R2CheckBox.Value = false;
                app.AllCheckBox.Value = false;
            end
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxChanged(app, event)
            value = app.AllCheckBox.Value;
            if value
                app.L1CheckBox.Value = false;
                app.L2CheckBox.Value = false;
                app.L3CheckBox.Value = false;
                app.R1CheckBox.Value = false;
                app.R2CheckBox.Value = false;
                app.R3CheckBox.Value = false;
            end
        end

        % Button pushed function: StartFrameButtonSwing
        function StartFrameButtonSwingPushed(app, event)
            % Get cursor position
            dcm_obj = datacursormode(app.figure_handles.behavior_classification);
            dcinfo = getCursorInfo(dcm_obj);
            if ~isempty(dcinfo)
                app.StartFrameEditFieldSwing.Value = dcinfo.DataIndex;
            else            
                app.StartFrameEditFieldSwing.Value = app.current_frame;
            end
        end

        % Button pushed function: EndFrameButtonSwing
        function EndFrameButtonSwingPushed(app, event)
            % Get cursor position
            dcm_obj = datacursormode(app.figure_handles.behavior_classification);
            dcinfo = getCursorInfo(dcm_obj);
            if ~isempty(dcinfo)
                app.EndFrameEditFieldSwing.Value = dcinfo.DataIndex;
            else            
                app.EndFrameEditFieldSwing.Value = app.current_frame;
            end
        end

        % Button pushed function: SetToOneButtonSwing
        function SetToOneButtonSwingPushed(app, event)
            col = [];
            if app.L1CheckBox.Value 
                col = 1;
            elseif app.L2CheckBox.Value
                col = 2;
            elseif app.L3CheckBox.Value
                col = 3;
            elseif app.R1CheckBox.Value
                col = 4;   
            elseif app.R2CheckBox.Value
                col = 5; 
            elseif app.R3CheckBox.Value
                col = 6; 
            elseif app.AllCheckBox.Value
                col = 1:6; 
            end

            if ~isempty(col) ...
                    && ~isempty(app.StartFrameEditFieldSwing.Value) ...
                    && ~isempty(app.EndFrameEditFieldSwing.Value)
                app.classification_data.proofread_swing(app.StartFrameEditFieldSwing.Value:app.EndFrameEditFieldSwing.Value, col) = 1;
                plot_swing_classification(app)
            end
        end

        % Button pushed function: SetToZeroButtonSwing
        function SetToZeroButtonSwingPushed(app, event)
            col = [];
            if app.L1CheckBox.Value 
                col = 1;
            elseif app.L2CheckBox.Value
                col = 2;
            elseif app.L3CheckBox.Value
                col = 3;
            elseif app.R1CheckBox.Value
                col = 4;   
            elseif app.R2CheckBox.Value
                col = 5; 
            elseif app.R3CheckBox.Value
                col = 6; 
            elseif app.AllCheckBox.Value
                col = 1:6; 
            end

            if ~isempty(col) ...
                    && ~isempty(app.StartFrameEditFieldSwing.Value) ...
                    && ~isempty(app.EndFrameEditFieldSwing.Value)
                app.classification_data.proofread_swing(app.StartFrameEditFieldSwing.Value:app.EndFrameEditFieldSwing.Value, col) = 0;
                plot_swing_classification(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.OpenMenu = uimenu(app.File);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.CloseMenu = uimenu(app.File);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.SaveClassificationMenu = uimenu(app.File);
            app.SaveClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveClassificationMenuSelected, true);
            app.SaveClassificationMenu.Text = 'Save Classification';
            app.SaveDisplayMenu = uimenu(app.File);
            app.SaveDisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDisplayMenuSelected, true);
            app.SaveDisplayMenu.Text = 'Save Display';
            app.SaveVideoMenu = uimenu(app.File);
            app.SaveVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveVideoMenuSelected, true);
            app.SaveVideoMenu.Text = 'Save Video';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.CamerasMenu = uimenu(app.ViewMenu);
            app.CamerasMenu.MenuSelectedFcn = createCallbackFcn(app, @CamerasMenuSelected, true);
            app.CamerasMenu.Text = 'Cameras';
            app.VelocityMenu = uimenu(app.ViewMenu);
            app.VelocityMenu.MenuSelectedFcn = createCallbackFcn(app, @VelocityMenuSelected, true);
            app.VelocityMenu.Text = 'Velocity';
            app.PathMenu = uimenu(app.ViewMenu);
            app.PathMenu.MenuSelectedFcn = createCallbackFcn(app, @PathMenuSelected, true);
            app.PathMenu.Text = 'Path';
            app.StimulusMenu = uimenu(app.ViewMenu);
            app.StimulusMenu.MenuSelectedFcn = createCallbackFcn(app, @StimulusMenuSelected, true);
            app.StimulusMenu.Text = 'Stimulus';
            app.CameraFramesMenu = uimenu(app.ViewMenu);
            app.CameraFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @CameraFramesMenuSelected, true);
            app.CameraFramesMenu.Text = 'Camera Frames';
            app.KeypointsMenu = uimenu(app.ViewMenu);
            app.KeypointsMenu.MenuSelectedFcn = createCallbackFcn(app, @KeypointsMenuSelected, true);
            app.KeypointsMenu.Text = 'Keypoints';
            app.BehaviorClassificationMenu = uimenu(app.ViewMenu);
            app.BehaviorClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @BehaviorClassificationMenuSelected, true);
            app.BehaviorClassificationMenu.Text = 'Behavior Classification';
            app.SwingClassificationMenu = uimenu(app.ViewMenu);
            app.SwingClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @SwingClassificationMenuSelected, true);
            app.SwingClassificationMenu.Text = 'Swing Classification';
            app.Panel = uipanel(app.UIFigure);
            app.CurrentTimeLabel = uilabel(app.Panel);
            app.CurrentTimeLabel.Text = 'Current time';
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time';
            app.FrameLabel = uilabel(app.Panel);
            app.FrameLabel.Text = 'Frame';
            app.CurrentFrameLabel = uilabel(app.Panel);
            app.CurrentFrameLabel.Text = 'Current frame';
            app.TrialLabel = uilabel(app.Panel);
            app.TrialLabel.Text = 'Trial';
            app.ReplayspeedSliderLabel = uilabel(app.UIFigure);
            app.ReplayspeedSliderLabel.Text = 'Replay speed';
            app.ReplaySpeedSlider = uislider(app.UIFigure);
            app.ReplaySpeedSlider.Limits = [1 300];
            app.ReplaySpeedSlider.MajorTicks = [];
            app.ReplaySpeedSlider.MajorTickLabels = {''};
            app.ReplaySpeedSlider.MinorTicks = [];
            app.ReplaySpeedSlider.Value = 1;
            app.RwdButton = uibutton(app.UIFigure, 'push');
            app.RwdButton.ButtonPushedFcn = createCallbackFcn(app, @RwdButtonPushed, true);
            app.RwdButton.Text = 'Rwd';
            app.FwdButton = uibutton(app.UIFigure, 'push');
            app.FwdButton.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.FwdButton.Text = 'Fwd';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.MinorTicks = [];
            app.SingleFwdButton = uibutton(app.UIFigure, 'push');
            app.SingleFwdButton.ButtonPushedFcn = createCallbackFcn(app, @SingleFwdButtonPushed, true);
            app.SingleFwdButton.Text = '>';
            app.SingleRwdButton = uibutton(app.UIFigure, 'push');
            app.SingleRwdButton.ButtonPushedFcn = createCallbackFcn(app, @SingleRwdButtonPushed, true);
            app.SingleRwdButton.Text = '<';
            app.AdjustKeypointsPanel = uipanel(app.UIFigure);
            app.AdjustKeypointsPanel.Title = 'Adjust keypoints ';
            app.ExportKeypointsButton = uibutton(app.AdjustKeypointsPanel, 'push');
            app.ExportKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportKeypointsButtonPushed, true);
            app.ExportKeypointsButton.Text = 'Export';
            app.Camera02CheckBox = uicheckbox(app.AdjustKeypointsPanel);
            app.Camera02CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera02CheckBoxSelected, true);
            app.Camera02CheckBox.Text = 'Camera 02';
            app.Camera01CheckBox = uicheckbox(app.AdjustKeypointsPanel);
            app.Camera01CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera01CheckBoxSelected, true);
            app.Camera01CheckBox.Text = 'Camera 01';
            app.InteractiveKeypointsCheckBox = uicheckbox(app.AdjustKeypointsPanel);
            app.InteractiveKeypointsCheckBox.Text = 'Keypoints';
            app.KeypointNamesCheckBox = uicheckbox(app.AdjustKeypointsPanel);
            app.KeypointNamesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowKeypointNamesChanged, true);
            app.KeypointNamesCheckBox.Text = 'Keypoint names';
            app.AdjustKeypointsButton = uibutton(app.AdjustKeypointsPanel, 'push');
            app.AdjustKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustKeypointsButtonPushed, true);
            app.AdjustKeypointsButton.Text = 'Adjust';
            app.ClassifySwingPanel = uipanel(app.UIFigure);
            app.ClassifySwingPanel.Title = 'Classify swing';
            app.SetToZeroButtonSwing = uibutton(app.ClassifySwingPanel, 'push');
            app.SetToZeroButtonSwing.ButtonPushedFcn = createCallbackFcn(app, @SetToZeroButtonSwingPushed, true);
            app.SetToZeroButtonSwing.Text = 'Zero';
            app.SetToOneButtonSwing = uibutton(app.ClassifySwingPanel, 'push');
            app.SetToOneButtonSwing.ButtonPushedFcn = createCallbackFcn(app, @SetToOneButtonSwingPushed, true);
            app.SetToOneButtonSwing.Text = 'One';
            app.EndFrameEditFieldSwing = uieditfield(app.ClassifySwingPanel, 'numeric');
            app.StartFrameEditFieldSwing = uieditfield(app.ClassifySwingPanel, 'numeric');
            app.EndFrameButtonSwing = uibutton(app.ClassifySwingPanel, 'push');
            app.EndFrameButtonSwing.ButtonPushedFcn = createCallbackFcn(app, @EndFrameButtonSwingPushed, true);
            app.EndFrameButtonSwing.Text = 'End';
            app.StartFrameButtonSwing = uibutton(app.ClassifySwingPanel, 'push');
            app.StartFrameButtonSwing.ButtonPushedFcn = createCallbackFcn(app, @StartFrameButtonSwingPushed, true);
            app.StartFrameButtonSwing.Text = 'Start';
            app.R3CheckBox = uicheckbox(app.ClassifySwingPanel);
            app.R3CheckBox.ValueChangedFcn = createCallbackFcn(app, @R3CheckBoxChanged, true);
            app.R3CheckBox.Text = 'R3';
            app.R2CheckBox = uicheckbox(app.ClassifySwingPanel);
            app.R2CheckBox.ValueChangedFcn = createCallbackFcn(app, @R2CheckBoxChanged, true);
            app.R2CheckBox.Text = 'R2';
            app.R1CheckBox = uicheckbox(app.ClassifySwingPanel);
            app.R1CheckBox.ValueChangedFcn = createCallbackFcn(app, @R1CheckBoxChanged, true);
            app.R1CheckBox.Text = 'R1';
            app.L3CheckBox = uicheckbox(app.ClassifySwingPanel);
            app.L3CheckBox.ValueChangedFcn = createCallbackFcn(app, @L3CheckBoxChanged, true);
            app.L3CheckBox.Text = 'L3';
            app.L2CheckBox = uicheckbox(app.ClassifySwingPanel);
            app.L2CheckBox.ValueChangedFcn = createCallbackFcn(app, @L2CheckBoxChanged, true);
            app.L2CheckBox.Text = 'L2';
            app.L1CheckBox = uicheckbox(app.ClassifySwingPanel);
            app.L1CheckBox.ValueChangedFcn = createCallbackFcn(app, @L1CheckBoxChanged, true);
            app.L1CheckBox.Text = 'L1';
            app.L1CheckBox.Value = true;
            app.AllCheckBox = uicheckbox(app.ClassifySwingPanel);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxChanged, true);
            app.AllCheckBox.Text = 'All';
            app.ClassifyBehaviorPanel = uipanel(app.UIFigure);
            app.ClassifyBehaviorPanel.Title = 'Classify behavior';
            app.SetToZeroButtonBehavior = uibutton(app.ClassifyBehaviorPanel, 'push');
            app.SetToZeroButtonBehavior.ButtonPushedFcn = createCallbackFcn(app, @SetToZeroButtonBehaviorPushed, true);
            app.SetToZeroButtonBehavior.Text = 'Zero';
            app.SetToOneButtonBehavior = uibutton(app.ClassifyBehaviorPanel, 'push');
            app.SetToOneButtonBehavior.ButtonPushedFcn = createCallbackFcn(app, @SetToOneButtonBehaviorPushed, true);
            app.SetToOneButtonBehavior.Text = 'One';
            app.EndFrameEditFieldBehavior = uieditfield(app.ClassifyBehaviorPanel, 'numeric');
            app.StartFrameEditFieldBehavior = uieditfield(app.ClassifyBehaviorPanel, 'numeric');
            app.EndFrameButtonBehavior = uibutton(app.ClassifyBehaviorPanel, 'push');
            app.EndFrameButtonBehavior.ButtonPushedFcn = createCallbackFcn(app, @EndFrameButtonBehaviorPushed, true);
            app.EndFrameButtonBehavior.Text = 'End';
            app.StartFrameButtonBehavior = uibutton(app.ClassifyBehaviorPanel, 'push');
            app.StartFrameButtonBehavior.ButtonPushedFcn = createCallbackFcn(app, @StartFrameButtonBehaviorPushed, true);
            app.StartFrameButtonBehavior.Text = 'Start';
            app.OtherCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.OtherCheckBox.ValueChangedFcn = createCallbackFcn(app, @OtherCheckBoxChanged, true);
            app.OtherCheckBox.Text = 'Other';
            app.FlightCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.FlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlightCheckBoxChanged, true);
            app.FlightCheckBox.Text = 'Flight';
            app.HLgroomingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.HLgroomingCheckBox.ValueChangedFcn = createCallbackFcn(app, @HLgroomingCheckBoxChanged, true);
            app.HLgroomingCheckBox.Text = 'HL grooming';
            app.FLgroomingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.FLgroomingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FLgroomingCheckBoxChanged, true);
            app.FLgroomingCheckBox.Text = 'FL grooming';
            app.WalkingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.WalkingCheckBox.ValueChangedFcn = createCallbackFcn(app, @WalkingCheckBoxChanged, true);
            app.WalkingCheckBox.Text = 'Walking';
            app.RestingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.RestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RestingCheckBoxChanged, true);
            app.RestingCheckBox.Text = 'Resting';
            app.RestingCheckBox.Value = true;
            app.AnalyzeCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.AnalyzeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeCheckBoxChanged, true);
            app.AnalyzeCheckBox.Text = 'Analyze';
            app.PushingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.PushingCheckBox.ValueChangedFcn = createCallbackFcn(app, @PushingCheckBoxChanged, true);
            app.PushingCheckBox.Text = 'Pushing';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.KeypointsCheckBox = uicheckbox(app.DisplayPanel);
            app.KeypointsCheckBox.Text = 'Keypoints';
            app.KeypointsCheckBox.Value = true;
            app.AdjustDisplayButton = uibutton(app.DisplayPanel, 'push');
            app.AdjustDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustDisplayButtonPushed, true);
            app.AdjustDisplayButton.Text = 'Adjust';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fly_on_the_ball_analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end